classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RecognizefaceinadatasetButton   matlab.ui.control.Button
        FacerecognitioninadatasetLabel  matlab.ui.control.Label
        RealtimefacedetectionusingwebcamLabel  matlab.ui.control.Label
        OpenVideoButton                 matlab.ui.control.Button
        EnterlocationofimageEditField_2  matlab.ui.control.EditField
        EnterlocationofimageEditField_2Label  matlab.ui.control.Label
        FacedetectioninavideoLabel      matlab.ui.control.Label
        FacedetectioninaimageLabel      matlab.ui.control.Label
        EnterlocationofimageEditField   matlab.ui.control.EditField
        EnterlocationofimageEditFieldLabel  matlab.ui.control.Label
        OpenwebcamanddetectButton       matlab.ui.control.Button
        OpenImageButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        Image_Location %to store location of image from input field
        Video_Location %to store location of video form input field
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenImageButton
        function OpenImageButtonPushed(app, event)
            the_Image      = imread(app.Image_Location);
            [width, height] = size(the_Image);
            
            if width>320
            the_Image = imresize(the_Image,[320 NaN]);
            end
            
            % Create a cascade detector object.
            faceDetector = vision.CascadeObjectDetector();
            
            %finding the bounding box that encloses the face on video frame
            face_Location = step(faceDetector, the_Image);
            
            % Draw the returned bounding box around the detected face.
            the_Image = insertShape(the_Image, 'Rectangle', face_Location);
            figure; 
            imshow(the_Image); 
            title('Detected face');
        end

        % Callback function
        function FacedetectioninvideoButtonPushed(app, event)

        end

        % Button pushed function: OpenwebcamanddetectButton
        function OpenwebcamanddetectButtonPushed(app, event)
            %open webcam of system
            cam=webcam();

            %set resolution of the webcam
            cam.Resolution='640x480';

            %set first video frame by taking snapshot from webcam
            video_Frame = snapshot(cam);

            %open the video player
            video_Player = vision.VideoPlayer('Position', [100 100 640 480]);

            %create cascade object detector
            face_Detector=vision.CascadeObjectDetector();

            % Create a point tracker object
            point_Tracker=vision.PointTracker('MaxBidirectionalError', 2);
            run_loop=true;
            number_of_Points=0;
            frame_Count=0;
            while run_loop && frame_Count<400
                %take snapshot from webcam for video frame
                video_Frame=snapshot(cam);

                %convert video frame to gray frame
                gray_Frame = rgb2gray(video_Frame);

                %increase frame count
                frame_Count=frame_Count+1;

                %for detecting face movement
                if number_of_Points<10
                    %creating a face rectangle
                    face_Rectangle = face_Detector.step(gray_Frame);
                    if ~isempty(face_Rectangle)
                        % Detect feature points within the detected face region
                        points = detectMinEigenFeatures(gray_Frame, 'ROI', face_Rectangle(1, :));

                        %get location of feature points
                        xy_Points = points.Location;

                        %get the number of feature points
                        number_of_Points=size(xy_Points, 1);

                        %release previous point tracker
                        release(point_Tracker);

                        %initialize new point tracker object
                        initialize(point_Tracker, xy_Points, gray_Frame);

                        %get previous feature points
                        previous_Points = xy_Points;

                        % Convert the bounding box of the detected face to points
                        rectangle=bbox2points(face_Rectangle(1, :));

                        % Reshape the transformed points into a polygon
                        face_Polygon=reshape(rectangle', 1, []);

                        % Draw the transformed polygon on the current frame
                        video_Frame=insertShape(video_Frame, 'Polygon', face_Polygon, 'LineWidth', 3);

                        % Mark the new feature points on the frame
                        video_Frame=insertMarker(video_Frame, xy_Points, '+', 'MarkerColor','white');
                    end
                else
                    % Track feature points in the current frame
                    [xy_Points, isFound] = step(point_Tracker, gray_Frame);

                    % Select only the found feature points
                    new_Points = xy_Points(isFound, :);
                    old_Points = previous_Points(isFound, :);

                    %get size of new feature points
                    number_of_Points=size(new_Points, 1);

                    % Check if enough feature points are found for transformation estimation
                    if number_of_Points>=10
                        % Estimate the geometric transformation between the old and new points
                        [xform, old_Points, new_Points] = estimateGeometricTransform(old_Points, new_Points, 'similarity', 'MaxDistance',4);

                        % Transform the bounding box points using the estimated transformation
                        rectangle = transformPointsForward(xform, rectangle);

                        % Reshape the transformed points into a polygon
                        face_Polygon = reshape(rectangle', 1, []);

                         % Draw the transformed polygon on the current frame
                        video_Frame=insertShape(video_Frame, 'Polygon', face_Polygon, 'LineWidth', 3);

                         % Mark the new feature points on the frame
                        video_Frame=insertMarker(video_Frame, new_Points, '+', 'MarkerColor','white');

                        % Update the previous feature points for the next iteration
                        previous_Points = new_Points;

                         % Set the new feature points for the point tracker
                        setPoints(point_Tracker, previous_Points);
                    end
                end
                 % Display the current frame with tracking information
                step(video_Player, video_Frame);

                %check if video player is still open
                run_loop=isOpen(video_Player);
            end

            %clear camera
            clear('cam');

            %release video player
            release(video_Player);

            %release point tracker
            release(point_Tracker);

            %release face detector
            release(face_Detector);
        end

        % Button pushed function: OpenVideoButton
        function OpenVideoButtonPushed(app, event)
            % Open the video file
            the_Video = VideoReader(app.Video_Location);
            % Read the first frame of the video
            video_Frame = readFrame(the_Video);
            
            % Create a cascade object detector for face detection
            face_Detector = vision.CascadeObjectDetector();
            % Detect faces in the first frame
            location_of_the_Face = step(face_Detector, video_Frame);
            
            % Draw rectangles around detected faces
            detected_Frame = insertShape(video_Frame,'Rectangle', location_of_the_Face);
            
            % Convert the bounding box of the first detected face to points
            rectangle_to_Points = bbox2points(location_of_the_Face(1,:));
            % Detect feature points within the detected face region
            feature_Points = detectMinEigenFeatures(rgb2gray(detected_Frame), 'ROI', location_of_the_Face);
            
            % Create a point tracker object
            pointTracker = vision.PointTracker('MaxBidirectionalError', 2);
            
            % Extract the location of the feature points
            feature_Points = feature_Points.Location;
            % Initialize the point tracker with the feature points and the first frame
            initialize(pointTracker, feature_Points, detected_Frame);
            
            % Set up parameters for displaying the video
            left = 0;
            bottom = 0;
            width = size(detected_Frame, 2);
            height = size(detected_Frame, 1);
            video_Player = vision.VideoPlayer('Position', [left bottom width height]);
            
            % Store the feature points for the previous frame
            previous_Points = feature_Points;
            
            % Loop through each frame of the video
            while hasFrame(the_Video)
            
                video_Frame = readFrame(the_Video);
                % Track feature points in the current frame
                [feature_Points, isFound] = step(pointTracker, video_Frame);
                % Select only the found feature points
                new_Points = feature_Points(isFound,:);
                old_Points = previous_Points(isFound,:);
                
                % Check if enough feature points are found for transformation estimation
                if size(new_Points, 1) >= 2
                    % Estimate the geometric transformation between the old and new points
                    [transformed_Rectangle, old_Points, new_Points] = ...
                        estimateGeometricTransform(old_Points, new_Points, ...
                        'similarity', 'MaxDistance', 4);
                    % Transform the bounding box points using the estimated transformation
                    rectangle_to_Points = transformPointsForward(transformed_Rectangle, rectangle_to_Points);
                  
                  % Reshape the transformed points into a polygon
                  reshaped_Rectangle = reshape(rectangle_to_Points', 1, []);
                  % Draw the transformed polygon on the current frame
                  detected_Frame = insertShape(video_Frame, 'Polygon', reshaped_Rectangle,'LineWidth', 2);
                  
                  % Mark the new feature points on the frame
                  detected_Frame = insertMarker(detected_Frame, new_Points, '+', 'Color', 'White');
                  
                  % Update the previous feature points for the next iteration
                  previous_Points = new_Points;
                  % Set the new feature points for the point tracker
                  setPoints(pointTracker, previous_Points);
                end
                % Display the current frame with tracking information
                step(video_Player, detected_Frame);
            end
        end

        % Value changed function: EnterlocationofimageEditField
        function EnterlocationofimageEditFieldValueChanged(app, event)
            value = app.EnterlocationofimageEditField.Value;
            app.Image_Location=value;
        end

        % Value changed function: EnterlocationofimageEditField_2
        function EnterlocationofimageEditField_2ValueChanged(app, event)
            value = app.EnterlocationofimageEditField_2.Value;
            app.Video_Location=value;
        end

        % Button pushed function: RecognizefaceinadatasetButton
        function RecognizefaceinadatasetButtonPushed(app, event)
            loaded_Image=load_database();
            random_Index=round(400*rand(1,1));          
            random_Image=loaded_Image(:,random_Index);                         
            rest_of_the_images=loaded_Image(:,[1:random_Index-1 random_Index+1:end]);         
            image_Signature=20;                            
            white_Image=uint8(ones(1,size(rest_of_the_images,2)));
            mean_value=uint8(mean(rest_of_the_images,2));                
            mean_Removed=rest_of_the_images-uint8(single(mean_value)*single(white_Image)); 
            L=single(mean_Removed)'*single(mean_Removed);
            [V,D]=eig(L);
            V=single(mean_Removed)*V;
            V=V(:,end:-1:end-(image_Signature-1));          
            all_image_Signatire=zeros(size(rest_of_the_images,2),image_Signature);
            for i=1:size(rest_of_the_images,2);
                all_image_Signatire(i,:)=single(mean_Removed(:,i))'*V;  
            end
            subplot(121);
            imshow(reshape(random_Image,112,92));
            title('Looking for this Face','FontWeight','bold','Fontsize',16,'color','red');
            subplot(122);
            p=random_Image-mean_value;
            s=single(p)'*V;
            z=[];
            for i=1:size(rest_of_the_images,2)
                z=[z,norm(all_image_Signatire(i,:)-s,2)];
                if(rem(i,20)==0),imshow(reshape(rest_of_the_images(:,i),112,92)),end;
                drawnow;
            end
            [a,i]=min(z);
            subplot(122);
            imshow(reshape(rest_of_the_images(:,i),112,92));
            title('Recognition Completed','FontWeight','bold','Fontsize',16,'color','red');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OpenImageButton = uibutton(app.UIFigure, 'push');
            app.OpenImageButton.ButtonPushedFcn = createCallbackFcn(app, @OpenImageButtonPushed, true);
            app.OpenImageButton.Text = 'Open Image';
            app.OpenwebcamanddetectButton = uibutton(app.UIFigure, 'push');
            app.OpenwebcamanddetectButton.ButtonPushedFcn = createCallbackFcn(app, @OpenwebcamanddetectButtonPushed, true);
            app.OpenwebcamanddetectButton.Text = 'Open webcam and detect';
            app.EnterlocationofimageEditFieldLabel = uilabel(app.UIFigure);
            app.EnterlocationofimageEditFieldLabel.Text = 'Enter location of image:';
            app.EnterlocationofimageEditField = uieditfield(app.UIFigure, 'text');
            app.EnterlocationofimageEditField.ValueChangedFcn = createCallbackFcn(app, @EnterlocationofimageEditFieldValueChanged, true);
            app.FacedetectioninaimageLabel = uilabel(app.UIFigure);
            app.FacedetectioninaimageLabel.Text = 'Face detection in a image';
            app.FacedetectioninavideoLabel = uilabel(app.UIFigure);
            app.FacedetectioninavideoLabel.Text = 'Face detection in a video';
            app.EnterlocationofimageEditField_2Label = uilabel(app.UIFigure);
            app.EnterlocationofimageEditField_2Label.Text = 'Enter location of image:';
            app.EnterlocationofimageEditField_2 = uieditfield(app.UIFigure, 'text');
            app.EnterlocationofimageEditField_2.ValueChangedFcn = createCallbackFcn(app, @EnterlocationofimageEditField_2ValueChanged, true);
            app.OpenVideoButton = uibutton(app.UIFigure, 'push');
            app.OpenVideoButton.ButtonPushedFcn = createCallbackFcn(app, @OpenVideoButtonPushed, true);
            app.OpenVideoButton.Text = 'Open Video';
            app.RealtimefacedetectionusingwebcamLabel = uilabel(app.UIFigure);
            app.RealtimefacedetectionusingwebcamLabel.Text = 'Real-time face detection using webcam';
            app.FacerecognitioninadatasetLabel = uilabel(app.UIFigure);
            app.FacerecognitioninadatasetLabel.Text = 'Face recognition in a dataset';
            app.RecognizefaceinadatasetButton = uibutton(app.UIFigure, 'push');
            app.RecognizefaceinadatasetButton.ButtonPushedFcn = createCallbackFcn(app, @RecognizefaceinadatasetButtonPushed, true);
            app.RecognizefaceinadatasetButton.Text = 'Recognize face in a dataset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end